classdef probDist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PoissonTab                      matlab.ui.container.Tab
        SelectGraphButtonGroup          matlab.ui.container.ButtonGroup
        CDFButton                       matlab.ui.control.RadioButton
        PMFButton                       matlab.ui.control.RadioButton
        px                              matlab.ui.control.NumericEditField
        pxLabel                         matlab.ui.control.Label
        Varience                        matlab.ui.control.NumericEditField
        VarienceEditFieldLabel          matlab.ui.control.Label
        ExpectedVal                     matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel     matlab.ui.control.Label
        Enterapositivevalueforxor0Label  matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        SelectavalueforLabel            matlab.ui.control.Label
        lambdaVal                       matlab.ui.control.Slider
        PoissonDiscreteLabel            matlab.ui.control.Label
        poissonAxes                     matlab.ui.control.UIAxes
        BinomialTab                     matlab.ui.container.Tab
        ExpectedValBinomial             matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_2   matlab.ui.control.Label
        pxBinomial                      matlab.ui.control.NumericEditField
        pxLabelBinomial                 matlab.ui.control.Label
        VarienceBinomial                matlab.ui.control.NumericEditField
        VarienceEditFieldLabelBinomial  matlab.ui.control.Label
        SelectGraphButtonGroupBinomial  matlab.ui.container.ButtonGroup
        CDFButton_2                     matlab.ui.control.RadioButton
        PMFButton_2                     matlab.ui.control.RadioButton
        pBinomial                       matlab.ui.control.Spinner
        EnteravalueforpSpinnerLabel     matlab.ui.control.Label
        nBinomial                       matlab.ui.control.Spinner
        EntervaluefornSpinnerLabel      matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        xBinomialEditField              matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        Enteravalueforxwhichmustbebetween0andnLabel  matlab.ui.control.Label
        BinomialDiscreteLabel           matlab.ui.control.Label
        binomialAxes                    matlab.ui.control.UIAxes
        GeometricTab                    matlab.ui.container.Tab
        SelectGraphButtonGroupGeometric  matlab.ui.container.ButtonGroup
        CDFButton_3                     matlab.ui.control.RadioButton
        PMFButton_3                     matlab.ui.control.RadioButton
        pGeometric                      matlab.ui.control.Spinner
        EnteravalueforpSpinnerLabel_2   matlab.ui.control.Label
        ParameterLabel_2                matlab.ui.control.Label
        pxGeometric                     matlab.ui.control.NumericEditField
        pxLabel_2                       matlab.ui.control.Label
        VarienceGeometric               matlab.ui.control.NumericEditField
        VarienceEditFieldLabel_2        matlab.ui.control.Label
        ExpectedValGeometric            matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_3   matlab.ui.control.Label
        xGeometricEditField             matlab.ui.control.NumericEditField
        xEditField_2Label_2             matlab.ui.control.Label
        EnterapositivevalueforxLabel    matlab.ui.control.Label
        GeometricDiscreteLabel          matlab.ui.control.Label
        geometricAxes                   matlab.ui.control.UIAxes
        BernoulliTab                    matlab.ui.container.Tab
        pxBernoulli                     matlab.ui.control.NumericEditField
        pxLabel_3                       matlab.ui.control.Label
        VarienceBernoulli               matlab.ui.control.NumericEditField
        VarienceEditFieldLabel_3        matlab.ui.control.Label
        ExpectedValBernoulli            matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_4   matlab.ui.control.Label
        SelectGraphButtonGroupBernoulli  matlab.ui.container.ButtonGroup
        CDFButton_4                     matlab.ui.control.RadioButton
        PMFButton_4                     matlab.ui.control.RadioButton
        pBernoulli                      matlab.ui.control.Spinner
        EnteravalueforpSpinnerLabel_3   matlab.ui.control.Label
        xBernoulliEditField             matlab.ui.control.NumericEditField
        xEditField_2Label_3             matlab.ui.control.Label
        ParameterLabel_3                matlab.ui.control.Label
        Enter0or1forxLabel              matlab.ui.control.Label
        BernoulliDiscreteLabel          matlab.ui.control.Label
        bernoulliAxes                   matlab.ui.control.UIAxes
        UniformTab                      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        fxUniform                       matlab.ui.control.NumericEditField
        pxLabel_4                       matlab.ui.control.Label
        VarienceUniform                 matlab.ui.control.NumericEditField
        VarienceEditFieldLabel_4        matlab.ui.control.Label
        ExpectedValUniform              matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_5   matlab.ui.control.Label
        SelectGraphButtonGroupBernoulli_2  matlab.ui.container.ButtonGroup
        CDFButton_5                     matlab.ui.control.RadioButton
        PDFButton                       matlab.ui.control.RadioButton
        xUniform                        matlab.ui.control.NumericEditField
        xEditField_2Label_6             matlab.ui.control.Label
        EnteravalueforxsuchthatLBxUBLabel  matlab.ui.control.Label
        EnterthevaluesfortheboundsLabel  matlab.ui.control.Label
        b                               matlab.ui.control.NumericEditField
        xEditField_2Label_5             matlab.ui.control.Label
        a                               matlab.ui.control.NumericEditField
        xEditField_2Label_4             matlab.ui.control.Label
        UniformContinuousLabel          matlab.ui.control.Label
        uniformAxes                     matlab.ui.control.UIAxes
        NormalTab                       matlab.ui.container.Tab
        SelectGraphButtonGroupBernoulli_3  matlab.ui.container.ButtonGroup
        CDFButton_6                     matlab.ui.control.RadioButton
        PDFButton_2                     matlab.ui.control.RadioButton
        fxNormal                        matlab.ui.control.NumericEditField
        pxLabel_5                       matlab.ui.control.Label
        VarienceNormal                  matlab.ui.control.NumericEditField
        VarienceEditFieldLabel_5        matlab.ui.control.Label
        ExpectedValNormal               matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_6   matlab.ui.control.Label
        sigmasquaredNormal              matlab.ui.control.Spinner
        EnteravalueforLabel_2           matlab.ui.control.Label
        muNormal                        matlab.ui.control.Spinner
        EnteravalueforLabel             matlab.ui.control.Label
        ParametersLabel_2               matlab.ui.control.Label
        xNormal                         matlab.ui.control.NumericEditField
        xEditField_2Label_7             matlab.ui.control.Label
        EnterarealnumberforxLabel       matlab.ui.control.Label
        NormalContinuousLabel           matlab.ui.control.Label
        normalAxes                      matlab.ui.control.UIAxes
        ExponentialTab                  matlab.ui.container.Tab
        Enterapositivevalueforxor0Label_2  matlab.ui.control.Label
        xExponential                    matlab.ui.control.NumericEditField
        xEditField_2Label_8             matlab.ui.control.Label
        ParameterLabel_4                matlab.ui.control.Label
        lambdaExponential               matlab.ui.control.Spinner
        EnteravalueforLabel_3           matlab.ui.control.Label
        SelectGraphButtonGroupBernoulli_4  matlab.ui.container.ButtonGroup
        CDFButton_7                     matlab.ui.control.RadioButton
        PDFButton_3                     matlab.ui.control.RadioButton
        fxExponential                   matlab.ui.control.NumericEditField
        pxLabel_6                       matlab.ui.control.Label
        VarienceExponential             matlab.ui.control.NumericEditField
        VarienceEditFieldLabel_6        matlab.ui.control.Label
        ExpectedValExponential          matlab.ui.control.NumericEditField
        ExpectedValueEditFieldLabel_7   matlab.ui.control.Label
        ExponentialContinuousLabel      matlab.ui.control.Label
        exponentialAxes                 matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        lambda % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lambda = app.lambdaVal.Value;
            poisson(app)
        end

        % Value changed function: lambdaVal
        function lambdaValValueChanged(app, event)
            app.lambda = app.lambdaVal.Value;
            poisson(app)
        end

        % Value changed function: ExpectedVal
        function ExpectedValValueChanged(app, event)
            poisson(app)
        end

        % Value changed function: Varience
        function VarienceValueChanged(app, event)
            poisson(app)
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            cla(app.poissonAxes)
            poisson(app)
        end

        % Selection changed function: SelectGraphButtonGroup
        function SelectGraphButtonGroupSelectionChanged(app, event)
            poisson(app)
        end

        % Value changed function: ExpectedValBinomial
        function ExpectedValBinomialValueChanged(app, event)
            binomial(app)
        end

        % Value changed function: VarienceBinomial
        function VarienceBinomialValueChanged(app, event)
            binomial(app)
        end

        % Value changed function: pxBinomial
        function pxBinomialValueChanged(app, event)
            binomial(app)
        end

        % Value changed function: pBinomial
        function pBinomialValueChanged(app, event)
            binomial(app)
        end

        % Value changed function: nBinomial
        function nBinomialValueChanged(app, event)
            binomial(app)
        end

        % Value changed function: xBinomialEditField
        function xBinomialEditFieldValueChanged(app, event)
            cla(app.binomialAxes)
            binomial(app)
        end

        % Selection changed function: SelectGraphButtonGroupBinomial
        function SelectGraphButtonGroupBinomialSelectionChanged(app, event)
            binomial(app)  
        end

        % Value changed function: xGeometricEditField
        function xGeometricEditFieldValueChanged(app, event)
            geometric(app)
        end

        % Value changed function: pGeometric
        function pGeometricValueChanged(app, event)
            geometric(app)
        end

        % Value changed function: ExpectedValGeometric
        function ExpectedValGeometricValueChanged(app, event)
            geometric(app)
        end

        % Value changed function: VarienceGeometric
        function VarienceGeometricValueChanged(app, event)
            geometric(app)
        end

        % Value changed function: pxGeometric
        function pxGeometricValueChanged(app, event)
            geometric(app)
        end

        % Selection changed function: SelectGraphButtonGroupGeometric
        function SelectGraphButtonGroupGeometricSelectionChanged(app, event)
           geometric(app)
        end

        % Value changed function: xBernoulliEditField
        function xBernoulliEditFieldValueChanged(app, event)
            bernoulli(app)
        end

        % Value changed function: pBernoulli
        function pBernoulliValueChanged(app, event)
            bernoulli(app)
        end

        % Selection changed function: SelectGraphButtonGroupBernoulli
        function SelectGraphButtonGroupBernoulliSelectionChanged(app, event)
            bernoulli(app)
        end

        % Value changed function: ExpectedValBernoulli
        function ExpectedValBernoulliValueChanged(app, event)
            bernoulli(app)
        end

        % Value changed function: VarienceBernoulli
        function VarienceBernoulliValueChanged(app, event)
            bernoulli(app)
        end

        % Value changed function: pxBernoulli
        function pxBernoulliValueChanged(app, event)
            bernoulli(app)
        end

        % Value changed function: a
        function aValueChanged(app, event)
uniform(app)            
        end

        % Value changed function: b
        function bValueChanged(app, event)
uniform(app)            
            
        end

        % Value changed function: xUniform
        function xUniformValueChanged(app, event)
uniform(app)            
            
        end

        % Selection changed function: SelectGraphButtonGroupBernoulli_2
        function SelectGraphButtonGroupBernoulli_2SelectionChanged(app, event)
uniform(app)            
            
        end

        % Value changed function: ExpectedValUniform
        function ExpectedValUniformValueChanged(app, event)
uniform(app)            
            
        end

        % Value changed function: VarienceUniform
        function VarienceUniformValueChanged(app, event)
uniform(app)            
            
        end

        % Value changed function: fxUniform
        function fxUniformValueChanged(app, event)
uniform(app)            
            
        end

        % Value changed function: xNormal
        function xNormalValueChanged(app, event)
normal(app)            
        end

        % Value changed function: muNormal
        function muNormalValueChanged(app, event)
normal(app)            
            
        end

        % Value changed function: sigmasquaredNormal
        function sigmasquaredNormalValueChanged(app, event)
normal(app)            
            
        end

        % Selection changed function: SelectGraphButtonGroupBernoulli_3
        function SelectGraphButtonGroupBernoulli_3SelectionChanged(app, event)
normal(app)            
            
        end

        % Value changed function: ExpectedValNormal
        function ExpectedValNormalValueChanged(app, event)
normal(app)            
            
        end

        % Value changed function: VarienceNormal
        function VarienceNormalValueChanged(app, event)
normal(app)            
            
        end

        % Value changed function: fxNormal
        function fxNormalValueChanged(app, event)
normal(app)            
            
        end

        % Value changed function: xExponential
        function xExponentialValueChanged(app, event)
            exponential(app)
        end

        % Value changed function: lambdaExponential
        function lambdaExponentialValueChanged(app, event)
            exponential(app)
        end

        % Selection changed function: SelectGraphButtonGroupBernoulli_4
        function SelectGraphButtonGroupBernoulli_4SelectionChanged(app, event)
            exponential(app)
        end

        % Value changed function: ExpectedValExponential
        function ExpectedValExponentialValueChanged(app, event)
            exponential(app)
        end

        % Value changed function: VarienceExponential
        function VarienceExponentialValueChanged(app, event)
            exponential(app)
        end

        % Value changed function: fxExponential
        function fxExponentialValueChanged(app, event)
            exponential(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PoissonTab = uitab(app.TabGroup);
            app.PoissonTab.Title = 'Poisson';
            app.poissonAxes = uiaxes(app.PoissonTab);
            title(app.poissonAxes, 'Title')
            xlabel(app.poissonAxes, 'X')
            ylabel(app.poissonAxes, 'Y')
            zlabel(app.poissonAxes, 'Z')
            app.PoissonDiscreteLabel = uilabel(app.PoissonTab);
            app.PoissonDiscreteLabel.Text = 'Poisson - Discrete';
            app.lambdaVal = uislider(app.PoissonTab);
            app.lambdaVal.Limits = [0 50];
            app.lambdaVal.ValueChangedFcn = createCallbackFcn(app, @lambdaValValueChanged, true);
            app.lambdaVal.Value = 20;
            app.SelectavalueforLabel = uilabel(app.PoissonTab);
            app.SelectavalueforLabel.Text = 'Select a value for λ';
            app.ParameterLabel = uilabel(app.PoissonTab);
            app.ParameterLabel.Text = 'Parameter';
            app.xEditFieldLabel = uilabel(app.PoissonTab);
            app.xEditFieldLabel.Text = 'x =';
            app.xEditField = uieditfield(app.PoissonTab, 'numeric');
            app.xEditField.Limits = [0 Inf];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.Enterapositivevalueforxor0Label = uilabel(app.PoissonTab);
            app.Enterapositivevalueforxor0Label.Text = 'Enter a positive value for x (or 0)';
            app.ExpectedValueEditFieldLabel = uilabel(app.PoissonTab);
            app.ExpectedValueEditFieldLabel.Text = 'Expected Value';
            app.ExpectedVal = uieditfield(app.PoissonTab, 'numeric');
            app.ExpectedVal.ValueChangedFcn = createCallbackFcn(app, @ExpectedValValueChanged, true);
            app.ExpectedVal.Editable = 'off';
            app.VarienceEditFieldLabel = uilabel(app.PoissonTab);
            app.VarienceEditFieldLabel.Text = 'Varience';
            app.Varience = uieditfield(app.PoissonTab, 'numeric');
            app.Varience.ValueChangedFcn = createCallbackFcn(app, @VarienceValueChanged, true);
            app.Varience.Editable = 'off';
            app.pxLabel = uilabel(app.PoissonTab);
            app.pxLabel.Text = 'p(x) =';
            app.px = uieditfield(app.PoissonTab, 'numeric');
            app.px.Editable = 'off';
            app.SelectGraphButtonGroup = uibuttongroup(app.PoissonTab);
            app.SelectGraphButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupSelectionChanged, true);
            app.SelectGraphButtonGroup.Title = 'Select Graph';
            app.PMFButton = uiradiobutton(app.SelectGraphButtonGroup);
            app.PMFButton.Text = 'PMF';
            app.PMFButton.Value = true;
            app.CDFButton = uiradiobutton(app.SelectGraphButtonGroup);
            app.CDFButton.Text = 'CDF';
            app.BinomialTab = uitab(app.TabGroup);
            app.BinomialTab.Title = 'Binomial';
            app.binomialAxes = uiaxes(app.BinomialTab);
            title(app.binomialAxes, 'Title')
            xlabel(app.binomialAxes, 'X')
            ylabel(app.binomialAxes, 'Y')
            zlabel(app.binomialAxes, 'Z')
            app.BinomialDiscreteLabel = uilabel(app.BinomialTab);
            app.BinomialDiscreteLabel.Text = 'Binomial - Discrete';
            app.Enteravalueforxwhichmustbebetween0andnLabel = uilabel(app.BinomialTab);
            app.Enteravalueforxwhichmustbebetween0andnLabel.Text = 'Enter a value for x, which must be between 0 and n';
            app.xEditField_2Label = uilabel(app.BinomialTab);
            app.xEditField_2Label.Text = 'x =';
            app.xBinomialEditField = uieditfield(app.BinomialTab, 'numeric');
            app.xBinomialEditField.Limits = [0 Inf];
            app.xBinomialEditField.ValueChangedFcn = createCallbackFcn(app, @xBinomialEditFieldValueChanged, true);
            app.ParametersLabel = uilabel(app.BinomialTab);
            app.ParametersLabel.Text = 'Parameters';
            app.EntervaluefornSpinnerLabel = uilabel(app.BinomialTab);
            app.EntervaluefornSpinnerLabel.Text = 'Enter value for n';
            app.nBinomial = uispinner(app.BinomialTab);
            app.nBinomial.Limits = [0 Inf];
            app.nBinomial.ValueChangedFcn = createCallbackFcn(app, @nBinomialValueChanged, true);
            app.EnteravalueforpSpinnerLabel = uilabel(app.BinomialTab);
            app.EnteravalueforpSpinnerLabel.Text = 'Enter a value for p';
            app.pBinomial = uispinner(app.BinomialTab);
            app.pBinomial.Step = 0.01;
            app.pBinomial.Limits = [0 1];
            app.pBinomial.ValueChangedFcn = createCallbackFcn(app, @pBinomialValueChanged, true);
            app.SelectGraphButtonGroupBinomial = uibuttongroup(app.BinomialTab);
            app.SelectGraphButtonGroupBinomial.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupBinomialSelectionChanged, true);
            app.SelectGraphButtonGroupBinomial.Title = 'Select Graph';
            app.PMFButton_2 = uiradiobutton(app.SelectGraphButtonGroupBinomial);
            app.PMFButton_2.Text = 'PMF';
            app.PMFButton_2.Value = true;
            app.CDFButton_2 = uiradiobutton(app.SelectGraphButtonGroupBinomial);
            app.CDFButton_2.Text = 'CDF';
            app.VarienceEditFieldLabelBinomial = uilabel(app.BinomialTab);
            app.VarienceEditFieldLabelBinomial.Text = 'Varience';
            app.VarienceBinomial = uieditfield(app.BinomialTab, 'numeric');
            app.VarienceBinomial.ValueChangedFcn = createCallbackFcn(app, @VarienceBinomialValueChanged, true);
            app.VarienceBinomial.Editable = 'off';
            app.pxLabelBinomial = uilabel(app.BinomialTab);
            app.pxLabelBinomial.Text = 'p(x) =';
            app.pxBinomial = uieditfield(app.BinomialTab, 'numeric');
            app.pxBinomial.ValueChangedFcn = createCallbackFcn(app, @pxBinomialValueChanged, true);
            app.pxBinomial.Editable = 'off';
            app.ExpectedValueEditFieldLabel_2 = uilabel(app.BinomialTab);
            app.ExpectedValueEditFieldLabel_2.Text = 'Expected Value';
            app.ExpectedValBinomial = uieditfield(app.BinomialTab, 'numeric');
            app.ExpectedValBinomial.ValueChangedFcn = createCallbackFcn(app, @ExpectedValBinomialValueChanged, true);
            app.ExpectedValBinomial.Editable = 'off';
            app.GeometricTab = uitab(app.TabGroup);
            app.GeometricTab.Title = 'Geometric';
            app.geometricAxes = uiaxes(app.GeometricTab);
            title(app.geometricAxes, 'Title')
            xlabel(app.geometricAxes, 'X')
            ylabel(app.geometricAxes, 'Y')
            zlabel(app.geometricAxes, 'Z')
            app.GeometricDiscreteLabel = uilabel(app.GeometricTab);
            app.GeometricDiscreteLabel.Text = 'Geometric - Discrete';
            app.EnterapositivevalueforxLabel = uilabel(app.GeometricTab);
            app.EnterapositivevalueforxLabel.Text = 'Enter a positive value for x';
            app.xEditField_2Label_2 = uilabel(app.GeometricTab);
            app.xEditField_2Label_2.Text = 'x =';
            app.xGeometricEditField = uieditfield(app.GeometricTab, 'numeric');
            app.xGeometricEditField.Limits = [1 Inf];
            app.xGeometricEditField.ValueChangedFcn = createCallbackFcn(app, @xGeometricEditFieldValueChanged, true);
            app.xGeometricEditField.Value = 1;
            app.ExpectedValueEditFieldLabel_3 = uilabel(app.GeometricTab);
            app.ExpectedValueEditFieldLabel_3.Text = 'Expected Value';
            app.ExpectedValGeometric = uieditfield(app.GeometricTab, 'numeric');
            app.ExpectedValGeometric.ValueChangedFcn = createCallbackFcn(app, @ExpectedValGeometricValueChanged, true);
            app.ExpectedValGeometric.Editable = 'off';
            app.VarienceEditFieldLabel_2 = uilabel(app.GeometricTab);
            app.VarienceEditFieldLabel_2.Text = 'Varience';
            app.VarienceGeometric = uieditfield(app.GeometricTab, 'numeric');
            app.VarienceGeometric.ValueChangedFcn = createCallbackFcn(app, @VarienceGeometricValueChanged, true);
            app.VarienceGeometric.Editable = 'off';
            app.pxLabel_2 = uilabel(app.GeometricTab);
            app.pxLabel_2.Text = 'p(x) =';
            app.pxGeometric = uieditfield(app.GeometricTab, 'numeric');
            app.pxGeometric.ValueChangedFcn = createCallbackFcn(app, @pxGeometricValueChanged, true);
            app.pxGeometric.Editable = 'off';
            app.ParameterLabel_2 = uilabel(app.GeometricTab);
            app.ParameterLabel_2.Text = 'Parameter';
            app.EnteravalueforpSpinnerLabel_2 = uilabel(app.GeometricTab);
            app.EnteravalueforpSpinnerLabel_2.Text = 'Enter a value for p';
            app.pGeometric = uispinner(app.GeometricTab);
            app.pGeometric.Step = 0.01;
            app.pGeometric.Limits = [0 1];
            app.pGeometric.ValueChangedFcn = createCallbackFcn(app, @pGeometricValueChanged, true);
            app.SelectGraphButtonGroupGeometric = uibuttongroup(app.GeometricTab);
            app.SelectGraphButtonGroupGeometric.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupGeometricSelectionChanged, true);
            app.SelectGraphButtonGroupGeometric.Title = 'Select Graph';
            app.PMFButton_3 = uiradiobutton(app.SelectGraphButtonGroupGeometric);
            app.PMFButton_3.Text = 'PMF';
            app.PMFButton_3.Value = true;
            app.CDFButton_3 = uiradiobutton(app.SelectGraphButtonGroupGeometric);
            app.CDFButton_3.Text = 'CDF';
            app.BernoulliTab = uitab(app.TabGroup);
            app.BernoulliTab.Title = 'Bernoulli';
            app.bernoulliAxes = uiaxes(app.BernoulliTab);
            title(app.bernoulliAxes, 'Title')
            xlabel(app.bernoulliAxes, 'X')
            ylabel(app.bernoulliAxes, 'Y')
            zlabel(app.bernoulliAxes, 'Z')
            app.BernoulliDiscreteLabel = uilabel(app.BernoulliTab);
            app.BernoulliDiscreteLabel.Text = 'Bernoulli - Discrete';
            app.Enter0or1forxLabel = uilabel(app.BernoulliTab);
            app.Enter0or1forxLabel.Text = 'Enter 0 or 1 for x';
            app.ParameterLabel_3 = uilabel(app.BernoulliTab);
            app.ParameterLabel_3.Text = 'Parameter';
            app.xEditField_2Label_3 = uilabel(app.BernoulliTab);
            app.xEditField_2Label_3.Text = 'x =';
            app.xBernoulliEditField = uieditfield(app.BernoulliTab, 'numeric');
            app.xBernoulliEditField.Limits = [0 1];
            app.xBernoulliEditField.RoundFractionalValues = 'on';
            app.xBernoulliEditField.ValueChangedFcn = createCallbackFcn(app, @xBernoulliEditFieldValueChanged, true);
            app.EnteravalueforpSpinnerLabel_3 = uilabel(app.BernoulliTab);
            app.EnteravalueforpSpinnerLabel_3.Text = 'Enter a value for p';
            app.pBernoulli = uispinner(app.BernoulliTab);
            app.pBernoulli.Step = 0.01;
            app.pBernoulli.Limits = [0 1];
            app.pBernoulli.ValueChangedFcn = createCallbackFcn(app, @pBernoulliValueChanged, true);
            app.SelectGraphButtonGroupBernoulli = uibuttongroup(app.BernoulliTab);
            app.SelectGraphButtonGroupBernoulli.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupBernoulliSelectionChanged, true);
            app.SelectGraphButtonGroupBernoulli.Title = 'Select Graph';
            app.PMFButton_4 = uiradiobutton(app.SelectGraphButtonGroupBernoulli);
            app.PMFButton_4.Text = 'PMF';
            app.PMFButton_4.Value = true;
            app.CDFButton_4 = uiradiobutton(app.SelectGraphButtonGroupBernoulli);
            app.CDFButton_4.Text = 'CDF';
            app.ExpectedValueEditFieldLabel_4 = uilabel(app.BernoulliTab);
            app.ExpectedValueEditFieldLabel_4.Text = 'Expected Value';
            app.ExpectedValBernoulli = uieditfield(app.BernoulliTab, 'numeric');
            app.ExpectedValBernoulli.ValueChangedFcn = createCallbackFcn(app, @ExpectedValBernoulliValueChanged, true);
            app.ExpectedValBernoulli.Editable = 'off';
            app.VarienceEditFieldLabel_3 = uilabel(app.BernoulliTab);
            app.VarienceEditFieldLabel_3.Text = 'Varience';
            app.VarienceBernoulli = uieditfield(app.BernoulliTab, 'numeric');
            app.VarienceBernoulli.ValueChangedFcn = createCallbackFcn(app, @VarienceBernoulliValueChanged, true);
            app.VarienceBernoulli.Editable = 'off';
            app.pxLabel_3 = uilabel(app.BernoulliTab);
            app.pxLabel_3.Text = 'p(x) =';
            app.pxBernoulli = uieditfield(app.BernoulliTab, 'numeric');
            app.pxBernoulli.ValueChangedFcn = createCallbackFcn(app, @pxBernoulliValueChanged, true);
            app.pxBernoulli.Editable = 'off';
            app.UniformTab = uitab(app.TabGroup);
            app.UniformTab.Title = 'Uniform';
            app.uniformAxes = uiaxes(app.UniformTab);
            title(app.uniformAxes, 'Title')
            xlabel(app.uniformAxes, 'X')
            ylabel(app.uniformAxes, 'Y')
            zlabel(app.uniformAxes, 'Z')
            app.UniformContinuousLabel = uilabel(app.UniformTab);
            app.UniformContinuousLabel.Text = 'Uniform - Continuous';
            app.xEditField_2Label_4 = uilabel(app.UniformTab);
            app.xEditField_2Label_4.Text = 'lower bound';
            app.a = uieditfield(app.UniformTab, 'numeric');
            app.a.RoundFractionalValues = 'on';
            app.a.ValueChangedFcn = createCallbackFcn(app, @aValueChanged, true);
            app.xEditField_2Label_5 = uilabel(app.UniformTab);
            app.xEditField_2Label_5.Text = 'upper bound';
            app.b = uieditfield(app.UniformTab, 'numeric');
            app.b.RoundFractionalValues = 'on';
            app.b.ValueChangedFcn = createCallbackFcn(app, @bValueChanged, true);
            app.EnterthevaluesfortheboundsLabel = uilabel(app.UniformTab);
            app.EnterthevaluesfortheboundsLabel.Text = 'Enter the values for the bounds';
            app.EnteravalueforxsuchthatLBxUBLabel = uilabel(app.UniformTab);
            app.EnteravalueforxsuchthatLBxUBLabel.Text = 'Enter a value for x such that LB ≤ x ≤ UB';
            app.xEditField_2Label_6 = uilabel(app.UniformTab);
            app.xEditField_2Label_6.Text = 'x =';
            app.xUniform = uieditfield(app.UniformTab, 'numeric');
            app.xUniform.ValueChangedFcn = createCallbackFcn(app, @xUniformValueChanged, true);
            app.SelectGraphButtonGroupBernoulli_2 = uibuttongroup(app.UniformTab);
            app.SelectGraphButtonGroupBernoulli_2.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupBernoulli_2SelectionChanged, true);
            app.SelectGraphButtonGroupBernoulli_2.Title = 'Select Graph';
            app.PDFButton = uiradiobutton(app.SelectGraphButtonGroupBernoulli_2);
            app.PDFButton.Text = 'PDF';
            app.PDFButton.Value = true;
            app.CDFButton_5 = uiradiobutton(app.SelectGraphButtonGroupBernoulli_2);
            app.CDFButton_5.Text = 'CDF';
            app.ExpectedValueEditFieldLabel_5 = uilabel(app.UniformTab);
            app.ExpectedValueEditFieldLabel_5.Text = 'Expected Value';
            app.ExpectedValUniform = uieditfield(app.UniformTab, 'numeric');
            app.ExpectedValUniform.ValueChangedFcn = createCallbackFcn(app, @ExpectedValUniformValueChanged, true);
            app.ExpectedValUniform.Editable = 'off';
            app.VarienceEditFieldLabel_4 = uilabel(app.UniformTab);
            app.VarienceEditFieldLabel_4.Text = 'Varience';
            app.VarienceUniform = uieditfield(app.UniformTab, 'numeric');
            app.VarienceUniform.ValueChangedFcn = createCallbackFcn(app, @VarienceUniformValueChanged, true);
            app.VarienceUniform.Editable = 'off';
            app.pxLabel_4 = uilabel(app.UniformTab);
            app.pxLabel_4.Text = 'f(x) =';
            app.fxUniform = uieditfield(app.UniformTab, 'numeric');
            app.fxUniform.ValueChangedFcn = createCallbackFcn(app, @fxUniformValueChanged, true);
            app.fxUniform.Editable = 'off';
            app.Label = uilabel(app.UniformTab);
            app.Label.Text = 'Notice: As the width of the bound range increases, the height of the pdf decreases and the slope of the cdf decreases as well!';
            app.NormalTab = uitab(app.TabGroup);
            app.NormalTab.Title = 'Normal';
            app.normalAxes = uiaxes(app.NormalTab);
            title(app.normalAxes, 'Title')
            xlabel(app.normalAxes, 'X')
            ylabel(app.normalAxes, 'Y')
            zlabel(app.normalAxes, 'Z')
            app.NormalContinuousLabel = uilabel(app.NormalTab);
            app.NormalContinuousLabel.Text = 'Normal - Continuous';
            app.EnterarealnumberforxLabel = uilabel(app.NormalTab);
            app.EnterarealnumberforxLabel.Text = 'Enter a real number for x';
            app.xEditField_2Label_7 = uilabel(app.NormalTab);
            app.xEditField_2Label_7.Text = 'x =';
            app.xNormal = uieditfield(app.NormalTab, 'numeric');
            app.xNormal.ValueChangedFcn = createCallbackFcn(app, @xNormalValueChanged, true);
            app.ParametersLabel_2 = uilabel(app.NormalTab);
            app.ParametersLabel_2.Text = 'Parameters';
            app.EnteravalueforLabel = uilabel(app.NormalTab);
            app.EnteravalueforLabel.Text = 'Enter a value for μ';
            app.muNormal = uispinner(app.NormalTab);
            app.muNormal.ValueChangedFcn = createCallbackFcn(app, @muNormalValueChanged, true);
            app.EnteravalueforLabel_2 = uilabel(app.NormalTab);
            app.EnteravalueforLabel_2.Text = 'Enter a value for σ²';
            app.sigmasquaredNormal = uispinner(app.NormalTab);
            app.sigmasquaredNormal.Step = 0.01;
            app.sigmasquaredNormal.ValueChangedFcn = createCallbackFcn(app, @sigmasquaredNormalValueChanged, true);
            app.ExpectedValueEditFieldLabel_6 = uilabel(app.NormalTab);
            app.ExpectedValueEditFieldLabel_6.Text = 'Expected Value';
            app.ExpectedValNormal = uieditfield(app.NormalTab, 'numeric');
            app.ExpectedValNormal.ValueChangedFcn = createCallbackFcn(app, @ExpectedValNormalValueChanged, true);
            app.ExpectedValNormal.Editable = 'off';
            app.VarienceEditFieldLabel_5 = uilabel(app.NormalTab);
            app.VarienceEditFieldLabel_5.Text = 'Varience';
            app.VarienceNormal = uieditfield(app.NormalTab, 'numeric');
            app.VarienceNormal.ValueChangedFcn = createCallbackFcn(app, @VarienceNormalValueChanged, true);
            app.VarienceNormal.Editable = 'off';
            app.pxLabel_5 = uilabel(app.NormalTab);
            app.pxLabel_5.Text = 'f(x) =';
            app.fxNormal = uieditfield(app.NormalTab, 'numeric');
            app.fxNormal.ValueChangedFcn = createCallbackFcn(app, @fxNormalValueChanged, true);
            app.fxNormal.Editable = 'off';
            app.SelectGraphButtonGroupBernoulli_3 = uibuttongroup(app.NormalTab);
            app.SelectGraphButtonGroupBernoulli_3.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupBernoulli_3SelectionChanged, true);
            app.SelectGraphButtonGroupBernoulli_3.Title = 'Select Graph';
            app.PDFButton_2 = uiradiobutton(app.SelectGraphButtonGroupBernoulli_3);
            app.PDFButton_2.Text = 'PDF';
            app.PDFButton_2.Value = true;
            app.CDFButton_6 = uiradiobutton(app.SelectGraphButtonGroupBernoulli_3);
            app.CDFButton_6.Text = 'CDF';
            app.ExponentialTab = uitab(app.TabGroup);
            app.ExponentialTab.Title = 'Exponential';
            app.exponentialAxes = uiaxes(app.ExponentialTab);
            title(app.exponentialAxes, 'Title')
            xlabel(app.exponentialAxes, 'X')
            ylabel(app.exponentialAxes, 'Y')
            zlabel(app.exponentialAxes, 'Z')
            app.ExponentialContinuousLabel = uilabel(app.ExponentialTab);
            app.ExponentialContinuousLabel.Text = 'Exponential - Continuous';
            app.ExpectedValueEditFieldLabel_7 = uilabel(app.ExponentialTab);
            app.ExpectedValueEditFieldLabel_7.Text = 'Expected Value';
            app.ExpectedValExponential = uieditfield(app.ExponentialTab, 'numeric');
            app.ExpectedValExponential.ValueChangedFcn = createCallbackFcn(app, @ExpectedValExponentialValueChanged, true);
            app.ExpectedValExponential.Editable = 'off';
            app.VarienceEditFieldLabel_6 = uilabel(app.ExponentialTab);
            app.VarienceEditFieldLabel_6.Text = 'Varience';
            app.VarienceExponential = uieditfield(app.ExponentialTab, 'numeric');
            app.VarienceExponential.ValueChangedFcn = createCallbackFcn(app, @VarienceExponentialValueChanged, true);
            app.VarienceExponential.Editable = 'off';
            app.pxLabel_6 = uilabel(app.ExponentialTab);
            app.pxLabel_6.Text = 'f(x) =';
            app.fxExponential = uieditfield(app.ExponentialTab, 'numeric');
            app.fxExponential.ValueChangedFcn = createCallbackFcn(app, @fxExponentialValueChanged, true);
            app.fxExponential.Editable = 'off';
            app.SelectGraphButtonGroupBernoulli_4 = uibuttongroup(app.ExponentialTab);
            app.SelectGraphButtonGroupBernoulli_4.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupBernoulli_4SelectionChanged, true);
            app.SelectGraphButtonGroupBernoulli_4.Title = 'Select Graph';
            app.PDFButton_3 = uiradiobutton(app.SelectGraphButtonGroupBernoulli_4);
            app.PDFButton_3.Text = 'PDF';
            app.PDFButton_3.Value = true;
            app.CDFButton_7 = uiradiobutton(app.SelectGraphButtonGroupBernoulli_4);
            app.CDFButton_7.Text = 'CDF';
            app.EnteravalueforLabel_3 = uilabel(app.ExponentialTab);
            app.EnteravalueforLabel_3.Text = 'Enter a value for λ';
            app.lambdaExponential = uispinner(app.ExponentialTab);
            app.lambdaExponential.ValueChangedFcn = createCallbackFcn(app, @lambdaExponentialValueChanged, true);
            app.ParameterLabel_4 = uilabel(app.ExponentialTab);
            app.ParameterLabel_4.Text = 'Parameter';
            app.xEditField_2Label_8 = uilabel(app.ExponentialTab);
            app.xEditField_2Label_8.Text = 'x =';
            app.xExponential = uieditfield(app.ExponentialTab, 'numeric');
            app.xExponential.Limits = [0 Inf];
            app.xExponential.RoundFractionalValues = 'on';
            app.xExponential.ValueChangedFcn = createCallbackFcn(app, @xExponentialValueChanged, true);
            app.Enterapositivevalueforxor0Label_2 = uilabel(app.ExponentialTab);
            app.Enterapositivevalueforxor0Label_2.Text = 'Enter a positive value for x (or 0)';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Label_2 = uilabel(app.InfoTab);
            app.Label_2.Text = 'This applet is to help students taking a probability course visualize various different types of discrete and continuous distributions! I hope you have as much fun clicking around as I had making this! Contact: pvaldes@nd.edu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = probDist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end