classdef probabilityDist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        EnteravalueforxLabel    matlab.ui.control.Label
        PoissonLabel            matlab.ui.control.Label
        SelectavalueforLabel    matlab.ui.control.Label
        SelectGraphButtonGroup  matlab.ui.container.ButtonGroup
        CDFButton               matlab.ui.control.RadioButton
        PMFPDFButton            matlab.ui.control.RadioButton
        ParametersLabel         matlab.ui.control.Label
        px                      matlab.ui.control.NumericEditField
        pxEditFieldLabel        matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        pxLabel                 matlab.ui.control.Label
        Varience                matlab.ui.control.NumericEditField
        VarienceEditFieldLabel  matlab.ui.control.Label
        ExpectedVal             matlab.ui.control.NumericEditField
        ExpectedValueLabel      matlab.ui.control.Label
        lambdaVal               matlab.ui.control.Slider
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        lambda % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lambda = app.lambdaVal.Value;
            poisson(app)
        end

        % Value changed function: lambdaVal
        function lambdaValValueChanged(app, event)
            app.lambda = app.lambdaVal.Value;
            poisson(app)
        end

        % Value changed function: ExpectedVal
        function ExpectedValValueChanged(app, event)
               poisson(app)
               binomial(app)
        end

        % Value changed function: Varience
        function VarienceValueChanged(app, event)
            poisson(app)
            
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            poisson(app)
            
        end

        % Selection changed function: SelectGraphButtonGroup
        function SelectGraphButtonGroupSelectionChanged(app, event)
            cla(app.UIAxes)
            poisson(app)
        end

        % Callback function: not associated with a component
        function probDistValueChanged(app, event)
            app.SelectapvalueLabel.Visible = "off";
            app.SelectannvalueSpinnerLabel.Visible = "off";
            app.nBinomial.Visible = "off";
            app.pBinomial.Visible = "off";
            app.lambdaVal.Visible = "off";
            app.SelectavalueforLabel.Visible = "off";
            switch app.probDist.Value
               case "Poisson"
                   cla(app.UIAxes)
                   app.lambdaVal.Visible = "on";
                   app.SelectavalueforLabel.Visible = "on";
                   poisson(app)
               case "Binomial"
                   cla(app.UIAxes)
                   binomial(app)

           end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.lambdaVal = uislider(app.LeftPanel);
            app.lambdaVal.Limits = [0 50];
            app.lambdaVal.ValueChangedFcn = createCallbackFcn(app, @lambdaValValueChanged, true);
            app.lambdaVal.Value = 20;
            app.ExpectedValueLabel = uilabel(app.LeftPanel);
            app.ExpectedValueLabel.Text = 'Expected Value  ';
            app.ExpectedVal = uieditfield(app.LeftPanel, 'numeric');
            app.ExpectedVal.ValueChangedFcn = createCallbackFcn(app, @ExpectedValValueChanged, true);
            app.ExpectedVal.Editable = 'off';
            app.VarienceEditFieldLabel = uilabel(app.LeftPanel);
            app.VarienceEditFieldLabel.Text = 'Varience';
            app.Varience = uieditfield(app.LeftPanel, 'numeric');
            app.Varience.ValueChangedFcn = createCallbackFcn(app, @VarienceValueChanged, true);
            app.Varience.Editable = 'off';
            app.pxLabel = uilabel(app.LeftPanel);
            app.pxLabel.Text = 'x =';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xEditField.Limits = [0 Inf];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.pxEditFieldLabel = uilabel(app.LeftPanel);
            app.pxEditFieldLabel.Text = 'p(x) =';
            app.px = uieditfield(app.LeftPanel, 'numeric');
            app.px.Editable = 'off';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.SelectGraphButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectGraphButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectGraphButtonGroupSelectionChanged, true);
            app.SelectGraphButtonGroup.Title = 'Select Graph';
            app.PMFPDFButton = uiradiobutton(app.SelectGraphButtonGroup);
            app.PMFPDFButton.Text = 'PMF/PDF';
            app.PMFPDFButton.Value = true;
            app.CDFButton = uiradiobutton(app.SelectGraphButtonGroup);
            app.CDFButton.Text = 'CDF';
            app.SelectavalueforLabel = uilabel(app.LeftPanel);
            app.SelectavalueforLabel.Text = 'Select a value for λ';
            app.PoissonLabel = uilabel(app.LeftPanel);
            app.PoissonLabel.Text = 'Poisson';
            app.EnteravalueforxLabel = uilabel(app.LeftPanel);
            app.EnteravalueforxLabel.Text = 'Enter a value for x';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Distribution')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'P(X=x)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = probabilityDist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end